classdef BiddingV02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EVCS                         matlab.ui.Figure
        LoadDataButton               matlab.ui.control.Button
        NumberofChargingEventsEditFieldLabel  matlab.ui.control.Label
        Disp1                        matlab.ui.control.NumericEditField
        SetParametersButton          matlab.ui.control.Button
        DaysofHistoricaldataLabel    matlab.ui.control.Label
        Disp2                        matlab.ui.control.NumericEditField
        DatetargetdayEditFieldLabel  matlab.ui.control.Label
        Disp3                        matlab.ui.control.EditField
        CalculateButton              matlab.ui.control.Button
        Fig1                         matlab.ui.control.UIAxes
        DataTable                    matlab.ui.control.Table
        InputDataSummaryLabel        matlab.ui.control.Label
        Fig2                         matlab.ui.control.UIAxes
        OptBidTable                  matlab.ui.control.Table
        OptimumBiddingstrategyLabel  matlab.ui.control.Label
        SaveBiddingButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        DialogApp % The dialog box app
        Eff = 0.95;
        Price = [0.014 0.014 0.0028 0.0028];
        BinNumber = 5000;
        Data = [];
        DailyFCR = [];
        FCRDis = [];
        OptProfile = []; 
        AvProfit = []; 
    end
    
    methods (Access = public)
        
        function UpdateParameters(app,Eff,Price,BinNumber)
            
            app.SetParametersButton.Enable = 'on';  % Re-enable the Plot Options button
            app.Eff = Eff;
            app.Price = Price;
            app.BinNumber = BinNumber;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
              
            % Load Data
            filterspec = {'*.CSV;*.XLS;*.XLSX'};
            [f, p] = uigetfile(filterspec);
            drawnow; % Keep the window on top
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                
                app.SaveBiddingButton.Enable = 'off';
                app.LoadDataButton.Text = 'Loading ...';
                app.LoadDataButton.FontColor = [1 0 0];
                drawnow;
                
                [app.Data,NoEvemt,NoDay,Date] = PD_LoadData([p f]);
                
%                 message = sprintf('Fire hazard! \n Consider reducing temperature.');
%                 uialert(app.EVCS,message,'Warning');
                
                app.LoadDataButton.Text = 'Load Data';
                app.LoadDataButton.FontColor = [0 0 0];
                app.CalculateButton.Enable = 'on';
                drawnow;
                
                app.Disp1.Value = NoEvemt;
                app.Disp2.Value = NoDay;
                app.Disp3.Value = Date;
                

            end
            
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
           
            app.SetParametersButton.Enable = 'off';   % Disable Plot Options button while dialog is open
            app.DialogApp = ParDialog(app,app.Eff,app.Price,app.BinNumber);% Open the options dialog and pass inputs

        end

        % Close request function: EVCS
        function EVCSCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
           
            % Create new daily Profiles
            app.CalculateButton.Text = 'Calculating ...';
            app.CalculateButton.FontColor = [1 0 0];
            drawnow;
            
            % find the maximum Power of EV
            app.Data = PD_MaxPower(app.Data);
            
            % Create new daily Profiles
            app.DailyFCR = PD_FCR(app.Data,app.Eff);
            for Cnt = 1:size(app.DailyFCR.Date,1)                    
                Temp{Cnt,1} = char(datetime(app.DailyFCR.Date(Cnt,1:3)));
            end
            Temp2 = table(Temp,app.DailyFCR.Events,app.DailyFCR.Energy);
            app.DataTable.Data = Temp2;           
            
            % Create density function and calculate the optimum FCR
            app.FCRDis = PD_FCRDis(app.DailyFCR,app.BinNumber);
            app.OptProfile = PD_FindOptProfile(app.FCRDis,app.Price);
%             app.AvProfit = PD_Profit(app.DailyFCR,app.OptProfile,app.Price);
%             app.ProfitTable.Data = app.AvProfit;

            app.CalculateButton.Text = 'Calculate';
            app.CalculateButton.FontColor = [0 0 0];
            drawnow;
                        
            hold(app.Fig1,'off');
            plot(app.Fig1,1:96,app.FCRDis.dExpect,'LineWidth',2,'Color',[0 0 0],'DisplayName','FCR-D');
            hold(app.Fig1,'on');
            plot(app.Fig1,1:96,app.FCRDis.nExpect,'LineWidth',2,'Color',[1 0 0],'LineStyle','--','DisplayName','FCR-N');
            plot(app.Fig1,1:96,app.FCRDis.dnExpect,'LineWidth',2,'Color',[0 0 1],'LineStyle','-.','DisplayName','FCR-Dn');
            legend(app.Fig1,'show','Location','northwest');
            
            hold(app.Fig2,'off');
            plot(app.Fig2,1:96,app.OptProfile.d,'LineWidth',2,'Color',[0 0 0],'DisplayName','FCR-D');
            hold(app.Fig2,'on');
            plot(app.Fig2,1:96,app.OptProfile.n,'LineWidth',2,'Color',[1 0 0],'LineStyle','--','DisplayName','FCR-N');
            plot(app.Fig2,1:96,app.OptProfile.dn,'LineWidth',2,'Color',[0 0 1],'LineStyle','-.','DisplayName','FCR-Dn');
            legend(app.Fig2,'show','Location','northwest');
            
            % Table of Optimum bidding
            Hour = [1:24]';
            FCRN = mean(reshape(app.OptProfile.n,[4,24])',2);
            FCRD = mean(reshape(app.OptProfile.d,[4,24])',2);
            FCRDn = mean(reshape(app.OptProfile.dn,[4,24])',2);
            Bidding =table(Hour,FCRN,FCRD,FCRDn);
            app.OptBidTable.Data = Bidding; 
            app.SaveBiddingButton.Enable = 'on';
            
          
        end

        % Button pushed function: SaveBiddingButton
        function SaveBiddingButtonPushed(app, event)
            [file,path] = uiputfile('*.xlsx');
            filename = fullfile(path,file);
            writetable(app.OptBidTable.Data,filename);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EVCS = uifigure('Visible', 'off');
            app.EVCS.Name = 'Electrical Vehicle Charging Stations';
            app.EVCS.CloseRequestFcn = createCallbackFcn(app, @EVCSCloseRequest, true);
            app.LoadDataButton = uibutton(app.EVCS, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tooltip = {'The data must be in the following Format:'; ''; '''CustomerID StID StName ArrivalTime DepartureTime Energy(Wh) StationRate(kW)'''};
            app.LoadDataButton.Text = 'Load Data';
            app.NumberofChargingEventsEditFieldLabel = uilabel(app.EVCS);
            app.NumberofChargingEventsEditFieldLabel.Text = 'Number of Charging Events';
            app.Disp1 = uieditfield(app.EVCS, 'numeric');
            app.Disp1.ValueDisplayFormat = '%.0f';
            app.Disp1.Editable = 'off';
            app.SetParametersButton = uibutton(app.EVCS, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.DaysofHistoricaldataLabel = uilabel(app.EVCS);
            app.DaysofHistoricaldataLabel.Text = 'Days of Historical data';
            app.Disp2 = uieditfield(app.EVCS, 'numeric');
            app.Disp2.ValueDisplayFormat = '%.0f';
            app.Disp2.Editable = 'off';
            app.DatetargetdayEditFieldLabel = uilabel(app.EVCS);
            app.DatetargetdayEditFieldLabel.Text = 'Date (target day)';
            app.Disp3 = uieditfield(app.EVCS, 'text');
            app.Disp3.Value = '-';
            app.CalculateButton = uibutton(app.EVCS, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Tooltip = {''};
            app.CalculateButton.Text = 'Calculate';
            app.Fig1 = uiaxes(app.EVCS);
            title(app.Fig1, '')
            xlabel(app.Fig1, 'Time (h)')
            ylabel(app.Fig1, 'Expected FCR (kW)')
            app.Fig1.XLim = [0 96];
            app.Fig1.XTick = [0 16 32 48 64 80 96];
            app.Fig1.XTickLabel = {'0'; '4'; '8'; '12'; '16'; '20'; '24'};
            app.DataTable = uitable(app.EVCS);
            app.DataTable.ColumnName = {'Date'; 'Event #'; 'Energy (kWh)'};
            app.DataTable.RowName = {};
            app.InputDataSummaryLabel = uilabel(app.EVCS);
            app.InputDataSummaryLabel.Text = 'Input Data Summary';
            app.Fig2 = uiaxes(app.EVCS);
            title(app.Fig2, '')
            xlabel(app.Fig2, 'Time (h)')
            ylabel(app.Fig2, 'Optimum FCR (kW)')
            app.Fig2.XLim = [0 96];
            app.Fig2.XTick = [0 16 32 48 64 80 96];
            app.Fig2.XTickLabel = {'0'; '4'; '8'; '12'; '16'; '20'; '24'};
            app.OptBidTable = uitable(app.EVCS);
            app.OptBidTable.ColumnName = {'Hour'; 'FCR-N (kW)'; 'FCR-D (kW)'; 'FCR-Dn (kW)'};
            app.OptBidTable.RowName = {};
            app.OptimumBiddingstrategyLabel = uilabel(app.EVCS);
            app.OptimumBiddingstrategyLabel.Text = 'Optimum Bidding strategy';
            app.SaveBiddingButton = uibutton(app.EVCS, 'push');
            app.SaveBiddingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBiddingButtonPushed, true);
            app.SaveBiddingButton.Enable = 'off';
            app.SaveBiddingButton.Tooltip = {''};
            app.SaveBiddingButton.Text = 'Save Bidding';
            % Show the figure after all components are created
            app.EVCS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BiddingV02

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EVCS)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EVCS)
        end
    end
end