classdef ParDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParametersUIFigure     matlab.ui.Figure
        TheEVefficiencyLabel   matlab.ui.control.Label
        Input1                 matlab.ui.control.NumericEditField
        ThenumberofBinsinPDFcalculationEditFieldLabel  matlab.ui.control.Label
        Input6                 matlab.ui.control.NumericEditField
        TheCapacitypriceforFCRNMwhEditFieldLabel  matlab.ui.control.Label
        Input2                 matlab.ui.control.NumericEditField
        ThePenaltycostforFCRNMwhEditFieldLabel  matlab.ui.control.Label
        Input3                 matlab.ui.control.NumericEditField
        TheCapacitypriceforFCRDMwhEditFieldLabel  matlab.ui.control.Label
        Input4                 matlab.ui.control.NumericEditField
        ThePenaltycostforFCRDMwhEditFieldLabel  matlab.ui.control.Label
        Input5                 matlab.ui.control.NumericEditField
        FCRNDataLabel          matlab.ui.control.Label
        FCRDDataLabel          matlab.ui.control.Label
        MethodParametersLabel  matlab.ui.control.Label
        OkButton               matlab.ui.control.Button
        EVDataLabel            matlab.ui.control.Label
        CancelButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Eff, Price, BinNumber)
            app.CallingApp = MainApp; % Store main app object
            app.Input1.Value = Eff*100;
            app.Input2.Value = Price(1,1)*1000;
            app.Input3.Value = Price(1,2)*1000;
            app.Input4.Value = Price(1,3)*1000;
            app.Input5.Value = Price(1,4)*1000;
            app.Input6.Value = BinNumber;
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
                        
            Eff = app.Input1.Value/100;
            Price(1,1) = app.Input2.Value/1000;
            Price(1,2) = app.Input3.Value/1000;
            Price(1,3) = app.Input4.Value/1000;
            Price(1,4) = app.Input5.Value/1000;
            BinNumber = app.Input6.Value;
            
            UpdateParameters(app.CallingApp,Eff, Price, BinNumber);
            
            delete(app) % Delete the dialog box  
   
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.SetParametersButton.Enable = 'on'; % Enable the Plot Options button in main app
            delete(app) % Delete the dialog box
            
        end

        % Close request function: ParametersUIFigure
        function ParametersUIFigureCloseRequest(app, event)
            app.CallingApp.SetParametersButton.Enable = 'on'; % Enable the Plot Options button in main app
            delete(app) % Delete the dialog box            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParametersUIFigure = uifigure('Visible', 'off');
            app.ParametersUIFigure.Name = 'Parameters';
            app.ParametersUIFigure.CloseRequestFcn = createCallbackFcn(app, @ParametersUIFigureCloseRequest, true);
            app.TheEVefficiencyLabel = uilabel(app.ParametersUIFigure);
            app.TheEVefficiencyLabel.Text = 'The EV efficiency (%)';
            app.Input1 = uieditfield(app.ParametersUIFigure, 'numeric');
            app.Input1.Value = 95;
            app.ThenumberofBinsinPDFcalculationEditFieldLabel = uilabel(app.ParametersUIFigure);
            app.ThenumberofBinsinPDFcalculationEditFieldLabel.Text = 'The number of Bins in PDF calculation';
            app.Input6 = uieditfield(app.ParametersUIFigure, 'numeric');
            app.Input6.Value = 5000;
            app.TheCapacitypriceforFCRNMwhEditFieldLabel = uilabel(app.ParametersUIFigure);
            app.TheCapacitypriceforFCRNMwhEditFieldLabel.Text = 'The Capacity price for FCR-N (€/Mw,h)';
            app.Input2 = uieditfield(app.ParametersUIFigure, 'numeric');
            app.Input2.Tooltip = {'cvzxc'};
            app.Input2.Value = 14;
            app.ThePenaltycostforFCRNMwhEditFieldLabel = uilabel(app.ParametersUIFigure);
            app.ThePenaltycostforFCRNMwhEditFieldLabel.Text = 'The Penalty cost for FCR-N (€/Mw,h)';
            app.Input3 = uieditfield(app.ParametersUIFigure, 'numeric');
            app.Input3.Value = 14;
            app.TheCapacitypriceforFCRDMwhEditFieldLabel = uilabel(app.ParametersUIFigure);
            app.TheCapacitypriceforFCRDMwhEditFieldLabel.Text = 'The Capacity price for FCR-D (€/Mw,h)';
            app.Input4 = uieditfield(app.ParametersUIFigure, 'numeric');
            app.Input4.Value = 2.8;
            app.ThePenaltycostforFCRDMwhEditFieldLabel = uilabel(app.ParametersUIFigure);
            app.ThePenaltycostforFCRDMwhEditFieldLabel.Text = 'The Penalty cost for FCR-D (€/Mw,h)';
            app.Input5 = uieditfield(app.ParametersUIFigure, 'numeric');
            app.Input5.Value = 2.8;
            app.FCRNDataLabel = uilabel(app.ParametersUIFigure);
            app.FCRNDataLabel.Text = 'FCR-N Data';
            app.FCRDDataLabel = uilabel(app.ParametersUIFigure);
            app.FCRDDataLabel.Text = 'FCR-D Data';
            app.MethodParametersLabel = uilabel(app.ParametersUIFigure);
            app.MethodParametersLabel.Text = 'Method Parameters';
            app.OkButton = uibutton(app.ParametersUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.EVDataLabel = uilabel(app.ParametersUIFigure);
            app.EVDataLabel.Text = 'EV Data';
            app.CancelButton = uibutton(app.ParametersUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.ParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParametersUIFigure)
        end
    end
end